classdef LTspiceGraphingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NumberofReadingsrange26EditField  matlab.ui.control.NumericEditField
        NumberofReadingsrange26EditFieldLabel  matlab.ui.control.Label
        RunningTimeSecondsEditField  matlab.ui.control.NumericEditField
        RunningTimeSecondsEditFieldLabel  matlab.ui.control.Label
        GenerateGraphButton          matlab.ui.control.Button
        PlotTitleEditField           matlab.ui.control.EditField
        PlotTitleEditFieldLabel      matlab.ui.control.Label
        FileNameEditField            matlab.ui.control.EditField
        FileNameEditFieldLabel       matlab.ui.control.Label
        DataLocationEditField        matlab.ui.control.EditField
        DataLocationEditFieldLabel   matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateGraphButton
        function GenerateGraphButtonPushed(app, event)
            %% Gathering user input data

            fileLocation = app.DataLocationEditField.Value;         % file location text feild
            fileName = app.FileNameEditField.Value;                 % file name text feild
            plotTitle = app.PlotTitleEditField.Value;               % plot title text feild
            runTime = app.RunningTimeSecondsEditField.Value;        % run time numeric feild
            readingNo = app.NumberofReadingsrange26EditField.Value; % number of readings numeric feild

            data = importdata(append(fileLocation, '\', fileName)); % importing selected test datafile
            time = 1000000 * data.data(:, 1);                       % converting time into 

            %% Plotting Graph

            p = plot(app.UIAxes, time, data.data(:, 2), 'LineWidth', 1.5);
            hold(app.UIAxes, 'on')                          % retains current axes to plot all data sets
            for i = 3:readingNo+1                           % loops for each data set
                plot(app.UIAxes, time, data.data(:, i), 'LineWidth', 1.5);
            end
            hold(app.UIAxes, 'off')                         % plot all selected lines on graph

            title(app.UIAxes, plotTitle);                   % set graph title to user selected title
            xlabel(app.UIAxes, 'time (μs)');                % set x axes label
            ylabel(app.UIAxes, 'Voltage');                  % set y axes label
            
            if readingNo == 1                               % adds appropriate legend values
                legend(app.UIAxes, string(data.textdata(2)));
            elseif readingNo == 2
                legend(app.UIAxes, string(data.textdata(2)), string(data.textdata(3)));
            elseif readingNo == 3
                legend(app.UIAxes, string(data.textdata(2)), string(data.textdata(3)), string(data.textdata(4)));
            elseif readingNo == 4
                legend(app.UIAxes, string(data.textdata(2)), string(data.textdata(3)), string(data.textdata(4)), string(data.textdata(5)));
            elseif readingNo == 5
                legend(app.UIAxes, string(data.textdata(2)), string(data.textdata(3)), string(data.textdata(4)), string(data.textdata(5)), string(data.textdata(6)));
            elseif readingNo == 6
                legend(app.UIAxes, string(data.textdata(2)), string(data.textdata(3)), string(data.textdata(4)), string(data.textdata(5)), string(data.textdata(6)), string(data.textdata(7)));
            end

            app.UIAxes.XTick = 0:100:runTime;   % set x axes values in units of 100 μ seconds
            app.UIAxes.XLim = [0, runTime];     % limits the x axes to within the run time so data fills the graph

            %% Saves outputchart as with title of variable plotTitle and file type .png
            %  This section of code was found on mathworks uploaded by user
            %  Happy PhD on 5 May 2020
            
            % Create a temporary figure with axes.
            figScan = figure;
            figAxes = axes(figScan);
            
            % Copy all UIAxes children, take over axes limits and aspect ratio.
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLabel = app.UIAxes.XLabel;
            figAxes.YLabel = app.UIAxes.YLabel;
            figAxes.Title = app.UIAxes.Title;
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            
            if readingNo == 1
                legend(figAxes, string(data.textdata(2)));
            elseif readingNo == 2
                legend(figAxes, string(data.textdata(2)), string(data.textdata(3)));
            elseif readingNo == 3
                legend(figAxes, string(data.textdata(2)), string(data.textdata(3)), string(data.textdata(4)));
            elseif readingNo == 4
                legend(figAxes, string(data.textdata(2)), string(data.textdata(3)), string(data.textdata(4)), string(data.textdata(5)));
            elseif readingNo == 5
                legend(figAxes, string(data.textdata(2)), string(data.textdata(3)), string(data.textdata(4)), string(data.textdata(5)), string(data.textdata(6)));
            elseif readingNo == 6
                legend(figAxes, string(data.textdata(2)), string(data.textdata(3)), string(data.textdata(4)), string(data.textdata(5)), string(data.textdata(6)), string(data.textdata(7)));
            end

            % Save as png and fig files.
            saveas(figScan, plotTitle, 'png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (μs)')
            ylabel(app.UIAxes, 'Voltage')
            app.DataLocationEditFieldLabel = uilabel(app.UIFigure);
            app.DataLocationEditFieldLabel.Text = 'Data Location';
            app.DataLocationEditField = uieditfield(app.UIFigure, 'text');
            app.DataLocationEditField.Placeholder = 'C:/temp';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Placeholder = 'LTspiceOutput.txt';
            app.PlotTitleEditFieldLabel = uilabel(app.UIFigure);
            app.PlotTitleEditFieldLabel.Text = 'Plot Title';
            app.PlotTitleEditField = uieditfield(app.UIFigure, 'text');
            app.PlotTitleEditField.Placeholder = '555 Plot';
            app.GenerateGraphButton = uibutton(app.UIFigure, 'push');
            app.GenerateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButtonPushed, true);
            app.GenerateGraphButton.Text = 'Generate Graph';
            app.RunningTimeSecondsEditFieldLabel = uilabel(app.UIFigure);
            app.RunningTimeSecondsEditFieldLabel.Text = 'Running Time (μ Seconds)';
            app.RunningTimeSecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunningTimeSecondsEditField.Limits = [1 1000000];
            app.RunningTimeSecondsEditField.Value = 500;
            app.NumberofReadingsrange26EditFieldLabel = uilabel(app.UIFigure);
            app.NumberofReadingsrange26EditFieldLabel.Text = 'Number of Readings (range 2-6)';
            app.NumberofReadingsrange26EditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofReadingsrange26EditField.Limits = [1 6];
            app.NumberofReadingsrange26EditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LTspiceGraphingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end